classdef GUIprojectct < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CTScannerUIFigure             matlab.ui.Figure
        GenerateSIGraphPanel          matlab.ui.container.Panel
        SelectionButtonGroup          matlab.ui.container.ButtonGroup
        ReconstructedImageButton      matlab.ui.control.RadioButton
        OriginalImageButton           matlab.ui.control.RadioButton
        RunSIGraphButton              matlab.ui.control.Button
        SaveSIGraphButton             matlab.ui.control.Button
        SelectSampleTypePanel         matlab.ui.container.Panel
        AngleProjectionButtonGroup    matlab.ui.container.ButtonGroup
        projectionsButton_3           matlab.ui.control.RadioButton
        projectionsButton_2           matlab.ui.control.RadioButton
        projectionsButton             matlab.ui.control.RadioButton
        ChooseScannerMethodDropDown   matlab.ui.control.DropDown
        ChooseScannerMethodDropDownLabel  matlab.ui.control.Label
        GenerateCTImageButton         matlab.ui.control.Button
        SaveCTImageButton             matlab.ui.control.Button
        SIGraphGeneratorPanel         matlab.ui.container.Panel
        SIGraph                       matlab.ui.control.UIAxes
        CTImagePanel                  matlab.ui.container.Panel
        CTImage                       matlab.ui.control.UIAxes
        PhantomImagePanel             matlab.ui.container.Panel
        PhantomImage                  matlab.ui.control.UIAxes
        PhantomPanel                  matlab.ui.container.Panel
        SavePhantomImageButton        matlab.ui.control.Button
        GeneratePhantomDropDown       matlab.ui.control.DropDown
        GeneratePhantomDropDownLabel  matlab.ui.control.Label
        GeneratePhantomButton         matlab.ui.control.Button
        ConfigurationSizePanel        matlab.ui.container.Panel
        CircleRadiiDropDown           matlab.ui.control.DropDown
        CircleRadiiDropDownLabel      matlab.ui.control.Label
        RectangleDimensionsDropDown   matlab.ui.control.DropDown
        RectangleDimensionsDropDownLabel  matlab.ui.control.Label
        VirtualCTScannerLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        shape % Description
        phantom_canvas
        SI_Image_canvas
        SI_canvas
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        % function sg = cRadon(~, img, theta)
        % 
        %         step = 1;
        % 
        %         angles = 1:step:theta;
        %         sg = zeros(size(img, 1), length(theta));
        % 
        %         for i = 1:length(angles)
        %             sg(:, i) = sum(imrotate(img, -angles(i), 'bilinear', 'crop'));
        %         end
        % 
        %   end
        % 
        % function ir = cART(~, sg, theta, niter)
        %     % You can play with steps (deafault 1):
        %     step = 1;
        % 
        %     angles = 1:step:theta;
        %     [ir, tdiff] = deal(zeros(length(sg)));
        % 
        %     for i = 1:niter
        %         for j = 1:length(angles)
        %             ir = imrotate(ir, step, 'bicubic', 'crop');
        %             tmp = sum(repelem(sg(:, j), 1, length(ir)), 2);
        %             diff = (tmp - sum(ir, 2)) / length(ir);
        %             for k = 1:length(ir)
        %                 tdiff(:, k) = diff;
        %             end
        %             ir = ir + tdiff;
        %         end
        %     end
        % 
        %     % Crop final image (Optional)
        %     % [h, w] = size(sg);
        %     % ir = imcrop(ir, [(h-w)/2, (h-w)/2, w, w]);
        % 
        % end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeneratePhantomButton
        function GeneratePhantomButtonPushed(app, event)
            size = [256,256];
            app.phantom_canvas = zeros(size);

            cp = [size(1)/2 , size(2)/2];
            phantom_selection = app.GeneratePhantomDropDown.Value;

            if(strcmp(phantom_selection, 'Circle Phantom'))
                radius_value = app.CircleRadiiDropDown.Value;

                E1 = [1.0, 0.9, 0.9, 0, 0, 0;
                      -0.8, 0.8999, 0.8999, 0, 0, 0;
                      0.8, 0.2, 0.2, 0, 0, 0
                      -0.2, 0.06, 0.06, -0.11, 0.5, 0;
                      0.2, 0.05, 0.05, -0.11, 0.5, 0;
                      -0.2, 0.12, 0.12, -0.60, -0.1, 0;
                      0.2, 0.11, 0.11, -0.60, -0.1, 0;
                      -0.2, 0.17, 0.17, 0, -0.55, 0;
                      0.2, 0.16, 0.16, 0, -0.55, 0;
                ];
                
                if strcmpi(radius_value, '20%')
                    E1 = [1.0, 0.9, 0.9, 0, 0, 0;
                          -0.8, 0.8999, 0.8999, 0, 0, 0;
                          0.8, 0.2, 0.2, 0, 0, 0
                          -0.2, 0.06 * 1.2, 0.06 * 1.2, -0.11, 0.5, 0;
                          0.2, 0.05 * 1.2, 0.05 * 1.2, -0.11, 0.5, 0;
                          -0.2, 0.12 * 1.2, 0.12 * 1.2, -0.60, -0.1, 0;
                          0.2, 0.11 * 1.2, 0.11 * 1.2, -0.60, -0.1, 0;
                          -0.2, 0.17 * 1.2, 0.17 * 1.2, 0, -0.55, 0;
                          0.2, 0.16 * 1.2, 0.16 * 1.2, 0, -0.55, 0;
                    ];
                end

                [~, A2] = phantom(E1, 256);
                P1 = phantom(A2, 256);
                app.phantom_canvas = P1;
        
            elseif(strcmp(phantom_selection, 'Rectangular Phantom'))
                width = 0;%5*app.WidthSlider.Value;
                length = 0;%5*app.LengthSlider_2.Value;
                
                %rectangle dimension drop-down options
                dimension_value = app.RectangleDimensionsDropDown.Value;

                if strcmp(dimension_value, '60x30')
                    length = 60;
                    width = 30;
                elseif strcmp(dimension_value, '45x45')
                    length = 45;
                    width = 45;
                elseif strcmp(dimension_value, '30x60')
                    length = 30;
                    width = 60;
                end

                r_size = floor([width*2 length*2]);

                % get user input - rectangular size 
                % r_para[width, height]
                r_para = r_size;
        
                % x-axis = height
                % height x-axis starting point
                x1 = floor(cp(2) - r_para(2)/2);
                % height x-axis ending point
                x2 = x1 + r_para(2);
                
                % y-axis = width
                % width y-axix starting point
                y1 = floor(cp(1) - r_para(1)/2);
                % width y-axix ending point
                y2 = y1 + r_para(1);
        
                % draw the squre
                app.phantom_canvas(x1 : x2, y1 : y2) = 1;
                  
            else
                app.phantom_canvas = phantom(256);
            end

            imwrite(app.phantom_canvas,'./test.png');
            imshow(app.phantom_canvas,'parent', app.PhantomImage);
            
        end

        % Button pushed function: SavePhantomImageButton
        function SavePhantomImageButtonPushed(app, event)
            if (isempty(app.phantom_canvas))
                errordlg('Phantom not found. Please generate a phantom','Phantom Error')
            end

            imwrite(app.phantom_canvas,'./generate-phantom-test.png');
        end

        % Button pushed function: GenerateCTImageButton
        function GenerateCTImageButtonPushed(app, event)
            method_selection = app.ChooseScannerMethodDropDown.Value;
            % size = [256, 256];
            % app.SI_Image_canvas = zeros(size);
            if (isempty(app.phantom_canvas))
                errordlg('Phantom not found. Please generate a phantom','Phantom Error')
            end

            if strcmp(method_selection, 'Original CT Intensity')
                app.SI_Image_canvas = app.phantom_canvas;
            end
    
            % Radon
            % if strcmp(method_selection, 'Radon')
            %     app.SI_Image_canvas = app.phantom_canvas;
            % 
            %     % Determine which button in the AngleProjectionButtonGroup is selected
            %     selectedButton = app.AngleProjectionButtonGroup.SelectedObject;
            % 
            %     switch selectedButton
            %         case app.projectionsButton
            %             theta1 = 0:10:170;
            %             app.SI_Image_canvas = radon(app.SI_Image_canvas, theta1);
            %         case app.projectionsButton_2
            %             theta2 = 0:5:175;
            %             app.SI_Image_canvas = radon(app.SI_Image_canvas, theta2);
            %         case app.projectionsButton_3
            %             theta3 = 0:2:178;
            %             app.SI_Image_canvas = radon(app.SI_Image_canvas, theta3);
            %         otherwise
            %             disp('Unexpected case');
            %     end
            %     app.SI_Image_canvas = imresize(app.SI_Image_canvas, 2*size(app.phantom_canvas));
            % end
            
            % Filtered Backprojection
            if strcmp(method_selection, 'Filtered Backproject')
                % Perform inverse Radon transform (filtered back projection)
                app.SI_Image_canvas = app.phantom_canvas;
                output_size = max(size(app.phantom_canvas));
                theta1 = 0:10:170;
                theta2 = 0:5:175;
                theta3 = 0:2:178;
            
                selectedButton = app.AngleProjectionButtonGroup.SelectedObject;            
                switch selectedButton
                    case app.projectionsButton
                        app.SI_Image_canvas = radon(app.SI_Image_canvas, theta1);
                        dtheta1 = theta1(2) - theta1(1);
                        app.SI_Image_canvas = rescale(iradon(app.SI_Image_canvas, dtheta1, output_size));
                    case app.projectionsButton_2
                        app.SI_Image_canvas = radon(app.SI_Image_canvas, theta2);
                        dtheta2 = theta2(2) - (1);
                        app.SI_Image_canvas = rescale(iradon(app.SI_Image_canvas, dtheta2, output_size));
                    case app.projectionsButton_3
                        app.SI_Image_canvas = radon(app.SI_Image_canvas, theta3);
                        dtheta3 = theta3(2) - theta3(1);
                        app.SI_Image_canvas = rescale(iradon(app.SI_Image_canvas, dtheta3, output_size));
                    otherwise
                        disp('Unexpected case');
                end
                app.SI_Image_canvas = imresize(app.SI_Image_canvas, size(app.phantom_canvas));
            end
            
            imshow(app.SI_Image_canvas, 'Parent', app.CTImage);
        end

        % Button pushed function: RunSIGraphButton
        function GenerateSiGraphButtonPushed(app, event)
            if app.OriginalImageButton.Value == 1
                if (isempty(app.phantom_canvas))
                    errordlg('Phantom not found. Please generate a phantom','Phantom Error')
                end

                x = [0 255];
                y = [0 255];
                
                app.SI_canvas = improfile(app.phantom_canvas, x, y);
            elseif app.ReconstructedImageButton.Value == 1 
                if (isempty(app.SI_Image_canvas))
                    errordlg('CT Image not found. Please generate a CT scan image','CT Image Error')
                end

                x = [0 255];
                y = [0 255];
                
                app.SI_canvas = improfile(app.SI_Image_canvas, x, y);
            end

            plot(app.SIGraph, app.SI_canvas);        
        end

        % Button pushed function: SaveCTImageButton
        function SaveCTImageButtonPushed(app, event)
            if (isempty(app.SI_Image_canvas))
                errordlg('SI Image not found. Please generate a SI Image','SI Image Error')
            end
            imwrite(app.SI_Image_canvas,'./generate-SIimage-test.png');
        end

        % Button pushed function: SaveSIGraphButton
        function SaveSIGraphButtonPushed(app, event)
            if (isempty(app.SI_canvas))
                errordlg('SI Image not found. Please generate a SI Image','SI Image Error')
            end
            imwrite(app.SI_canvas,'./generate-SI-test.png');  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CTScannerUIFigure = uifigure('Visible', 'off');
            app.CTScannerUIFigure.Name = 'Virtual CT Scanner';
            app.VirtualCTScannerLabel = uilabel(app.CTScannerUIFigure);
            app.VirtualCTScannerLabel.Text = 'Virtual CT Scanner';
            app.PhantomPanel = uipanel(app.CTScannerUIFigure);
            app.PhantomPanel.Title = 'Phantom';
            app.ConfigurationSizePanel = uipanel(app.PhantomPanel);
            app.ConfigurationSizePanel.Title = 'Configuration Size';
            app.RectangleDimensionsDropDownLabel = uilabel(app.ConfigurationSizePanel);
            app.RectangleDimensionsDropDownLabel.Text = 'Rectangle Dimensions';
            app.RectangleDimensionsDropDown = uidropdown(app.ConfigurationSizePanel);
            app.RectangleDimensionsDropDown.Items = {'30x60', '45x45', '60x30'};
            app.RectangleDimensionsDropDown.Value = '60x30';
            app.CircleRadiiDropDownLabel = uilabel(app.ConfigurationSizePanel);
            app.CircleRadiiDropDownLabel.Text = 'Circle Radii';
            app.CircleRadiiDropDown = uidropdown(app.ConfigurationSizePanel);
            app.CircleRadiiDropDown.Items = {'0%', '20%'};
            app.CircleRadiiDropDown.Value = '0%';
            app.GeneratePhantomButton = uibutton(app.PhantomPanel, 'push');
            app.GeneratePhantomButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePhantomButtonPushed, true);
            app.GeneratePhantomButton.Text = 'Generate Phantom';
            app.GeneratePhantomDropDownLabel = uilabel(app.PhantomPanel);
            app.GeneratePhantomDropDownLabel.Text = 'Generate Phantom';
            app.GeneratePhantomDropDown = uidropdown(app.PhantomPanel);
            app.GeneratePhantomDropDown.Items = {'Circle Phantom', 'Rectangular Phantom', 'Head Phantom'};
            app.GeneratePhantomDropDown.Value = 'Circle Phantom';
            app.SavePhantomImageButton = uibutton(app.PhantomPanel, 'push');
            app.SavePhantomImageButton.ButtonPushedFcn = createCallbackFcn(app, @SavePhantomImageButtonPushed, true);
            app.SavePhantomImageButton.Text = 'Save Phantom Image';
            app.PhantomImagePanel = uipanel(app.CTScannerUIFigure);
            app.PhantomImagePanel.Title = 'Phantom Image';
            app.PhantomImage = uiaxes(app.PhantomImagePanel);
            zlabel(app.PhantomImage, 'Z')
            app.CTImagePanel = uipanel(app.CTScannerUIFigure);
            app.CTImagePanel.Title = 'Acquire CT Image';
            app.CTImage = uiaxes(app.CTImagePanel);
            zlabel(app.CTImage, 'Z')
            app.SIGraphGeneratorPanel = uipanel(app.CTScannerUIFigure);
            app.SIGraphGeneratorPanel.Title = 'SI Graph Generator';
            app.SIGraph = uiaxes(app.SIGraphGeneratorPanel);
            zlabel(app.SIGraph, 'Z')
            app.SelectSampleTypePanel = uipanel(app.CTScannerUIFigure);
            app.SelectSampleTypePanel.Title = 'Select Sample Type';
            app.SaveCTImageButton = uibutton(app.SelectSampleTypePanel, 'push');
            app.SaveCTImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCTImageButtonPushed, true);
            app.SaveCTImageButton.Text = 'Save CT Image';
            app.GenerateCTImageButton = uibutton(app.SelectSampleTypePanel, 'push');
            app.GenerateCTImageButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCTImageButtonPushed, true);
            app.GenerateCTImageButton.Text = 'Generate CT Image';
            app.ChooseScannerMethodDropDownLabel = uilabel(app.SelectSampleTypePanel);
            app.ChooseScannerMethodDropDownLabel.Text = 'Choose Scanner Method';
            app.ChooseScannerMethodDropDown = uidropdown(app.SelectSampleTypePanel);
            app.ChooseScannerMethodDropDown.Items = {'Original CT Intensity', 'Filtered Backproject'};
            app.ChooseScannerMethodDropDown.Value = 'Original CT Intensity';
            app.AngleProjectionButtonGroup = uibuttongroup(app.SelectSampleTypePanel);
            app.AngleProjectionButtonGroup.Title = 'Angle Projection';
            app.projectionsButton = uiradiobutton(app.AngleProjectionButtonGroup);
            app.projectionsButton.Text = '18 projections';
            app.projectionsButton.Value = true;
            app.projectionsButton_2 = uiradiobutton(app.AngleProjectionButtonGroup);
            app.projectionsButton_2.Text = '24 projections';
            app.projectionsButton_3 = uiradiobutton(app.AngleProjectionButtonGroup);
            app.projectionsButton_3.Text = '90 projections';
            app.GenerateSIGraphPanel = uipanel(app.CTScannerUIFigure);
            app.GenerateSIGraphPanel.Title = 'Generate SI Graph';
            app.SaveSIGraphButton = uibutton(app.GenerateSIGraphPanel, 'push');
            app.SaveSIGraphButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSIGraphButtonPushed, true);
            app.SaveSIGraphButton.Text = 'Save SI Graph';
            app.RunSIGraphButton = uibutton(app.GenerateSIGraphPanel, 'push');
            app.RunSIGraphButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSiGraphButtonPushed, true);
            app.RunSIGraphButton.Text = 'Run SI Graph';
            app.SelectionButtonGroup = uibuttongroup(app.GenerateSIGraphPanel);
            app.SelectionButtonGroup.Title = 'Selection';
            app.OriginalImageButton = uiradiobutton(app.SelectionButtonGroup);
            app.OriginalImageButton.Text = 'Original Image';
            app.OriginalImageButton.Value = true;
            app.ReconstructedImageButton = uiradiobutton(app.SelectionButtonGroup);
            app.ReconstructedImageButton.Text = 'Reconstructed Image';
            % Show the figure after all components are created
            app.CTScannerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIprojectct

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CTScannerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CTScannerUIFigure)
        end
    end
end